classdef visualize_light < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Menu                    matlab.ui.container.Menu
        RemoveSmallObjectsMenu  matlab.ui.container.Menu
        ZScoringMenu            matlab.ui.container.Menu
        HistogramMatchingMenu   matlab.ui.container.Menu
        ThresholdMenu           matlab.ui.container.Menu
        DecimateMenu            matlab.ui.container.Menu
        RotationMenu            matlab.ui.container.Menu
        RotateX180Menu          matlab.ui.container.Menu
        RotateY180Menu          matlab.ui.container.Menu
        TabGroup                matlab.ui.container.TabGroup
        PreprocessTab           matlab.ui.container.Tab
        LoadButton              matlab.ui.control.Button
        XY                      matlab.ui.control.UIAxes
        MaxProjection           matlab.ui.control.UIAxes
        DatasetEditFieldLabel   matlab.ui.control.Label
        DatasetEditField        matlab.ui.control.EditField
        ZSliderLabel            matlab.ui.control.Label
        ZSlider                 matlab.ui.control.Slider
        RDropDownLabel          matlab.ui.control.Label
        RDropDown               matlab.ui.control.DropDown
        GDropDownLabel          matlab.ui.control.Label
        GDropDown               matlab.ui.control.DropDown
        BDropDownLabel          matlab.ui.control.Label
        BDropDown               matlab.ui.control.DropDown
        UpdateButton            matlab.ui.control.Button
        AutoIDDropDownLabel     matlab.ui.control.Label
        AutoIDDropDown          matlab.ui.control.DropDown
        IDEditField             matlab.ui.control.EditField
        AnnotateButton          matlab.ui.control.Button
        Rotation                matlab.ui.control.Slider
        NeuronsListBoxLabel     matlab.ui.control.Label
        NeuronsListBox          matlab.ui.control.ListBox
        RunMPButton             matlab.ui.control.Button
        SaveMPButton            matlab.ui.control.Button
        AutoIDButton            matlab.ui.control.Button
        SaveIDsButton           matlab.ui.control.Button
        UserUncertainty         matlab.ui.control.NumericEditField
        MPTab                   matlab.ui.container.Tab
        XYS                     matlab.ui.control.UIAxes
        YZS                     matlab.ui.control.UIAxes
        XZS                     matlab.ui.control.UIAxes
        XSlider_2Label          matlab.ui.control.Label
        XSliderS                matlab.ui.control.Slider
        YSlider_2Label          matlab.ui.control.Label
        YSliderS                matlab.ui.control.Slider
        ZSlider_2Label          matlab.ui.control.Label
        ZSliderS                matlab.ui.control.Slider
    end


    properties (Access = private)
        p = addpath(genpath('.'));
        params = get_params();
        
        dataset 
        vdataset
        k
        
        recon
        vrecon
        shapes

        supervoxels = []
        
        auto_id
        
        datastack
        selectedidx
        annotations
    end

    methods (Access = private)
    
        function update_views(app)                
            color_indices = [str2num(app.RDropDown.Value), ...
                             str2num(app.GDropDown.Value), ...
                             str2num(app.BDropDown.Value)];
            app.vdataset = double(app.dataset(:,:,:,color_indices,:))/...
                double(prctile(app.dataset(:), 99.9));
            image(app.MaxProjection, squeeze(max(app.vdataset,[],3)));
%             app.XSliderValueChanging(app.XSlider);
%             app.YSliderValueChanging(app.YSlider);
            app.ZSliderValueChanging(app.ZSlider);
            
%             app.XSlider.Value = 1;
%             app.YSlider.Value = 1;
%             app.ZSlider.Value = 1;

            
            if ~isempty(app.supervoxels) && ~isempty(app.supervoxels(1))
%                 app.vrecon = app.recon(:,:,:,color_indices,:)/...
%                 double(prctile(app.dataset(:), 99.9));
            
%                 app.XSliderSValueChanging(app.XSlider);
%                 app.YSliderSValueChanging(app.YSlider);
%                 app.ZSliderSValueChanging(app.ZSlider);
            end
            
            
        end


        function PointSelected(app,im,hit)
            mouse_pos = flip(hit.IntersectionPoint(1:2));
            z = round(app.ZSlider.Value);
            point = [mouse_pos, z];
            [~,midx] = min(mean((app.supervoxels(1).mean-point).^2,2));
            
            switch hit.Button
                case 1
                    cidx = find(sum([im.YData; im.XData]' == app.supervoxels(1).mean(midx,[1,2]),2));
                    im.CData = [repmat([1,1,1], cidx-1, 1); [1,0,0]; repmat([1,1,1], length(im.XData)-cidx, 1)];
                    
            
                    app.selectedidx = midx;
                    if ~isempty(app.auto_id)
                        values = app.auto_id.ids_prob(midx,:);
                        probs = app.auto_id.assignment_prob_probs(midx,:);
                        str = strcat(arrayfun(@(x) num2str(x, '%.2f'), probs, 'UniformOutput', 0), ',', values);
                        app.AutoIDDropDown.Items = str;
                        app.IDEditField.Value = app.auto_id.ids{midx};
                        
                    end
                case 2
                    
                case 3
                    cidx = find(sum([im.YData; im.XData]' == app.supervoxels(1).mean(midx,[1,2]),2));
                    set(im,'XData',[im.XData(1:cidx-1),im.XData(cidx+1:end)], ...
                           'YData',[im.YData(1:cidx-1),im.YData(cidx+1:end)]);
%                     sz = size(app.recon);
%                     app.recon = app.recon - placement(sz(1:3), round(app.supervoxels.mean(midx,:)), app.shapes{midx});
%                     app.shapes = {app.shapes{1:midx-1}, app.shapes{midx+1:size(app.supervoxels.mean,1)}};
                    app.supervoxels = sub_sp(app.supervoxels, [1:midx-1,midx+1:size(app.supervoxels.mean,1)]);
%                     app.ZSliderValueChanging(app.ZSlider);
%                     app.update_views();
                    
            end

            
        end
        
        function KeyPressed(app,source,eventdata)
              % determine the key that was pressed
              keyPressed = eventdata.Key;
              if strcmpi(keyPressed,'space')
                  % the key that was pressed was the space bar so set focus to 
                  % the snap button
                  uicontrol(snap);
                  % invoke the callback for the snap button
                  snapshot_callback(snap,[]);
              end
        end
        
    end


    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            
            [path, name, ext] = fileparts(app.DatasetEditField.Value);
            
            if strcmp(ext, '.czi')
                [video, options] = load_czi(app.DatasetEditField.Value);
                
                tiff = [];
                tiff.video = uint16(video);
                tiff.options = options;
                
                save([app.DatasetEditField.Value(1:end-3), 'mat'], 'tiff', '-v7.3');
            end
            
            file = matfile([path,'/',name,'.mat']);
            
            try
                video = file.video;
            catch
                tiff = file.tiff;
                video = tiff.video;
                clear tiff;
            end
            
            app.dataset = video(:,:,:,[5,4,1,2]);
            
            
            app.RDropDown.Items = arrayfun(@num2str, 1:size(app.dataset, 4), 'UniformOutput', false);
            app.GDropDown.Items = arrayfun(@num2str, 1:size(app.dataset, 4), 'UniformOutput', false);
            app.BDropDown.Items = arrayfun(@num2str, 1:size(app.dataset, 4), 'UniformOutput', false);
            
%             app.XSlider.Limits = [1, size(app.dataset, 1)];
%             app.YSlider.Limits = [1, size(app.dataset, 2)];
            app.ZSlider.Limits = [1, size(app.dataset, 3)];
            
%             app.XSliderS.Limits = [1, size(app.dataset, 1)];
%             app.YSliderS.Limits = [1, size(app.dataset, 2)];
            app.ZSliderS.Limits = [1, size(app.dataset, 3)];
            
            app.update_views();
            
            daspect(app.XY,[1 1 1]);
            daspect(app.MaxProjection,[1 1 1]);
            daspect(app.XYS,[1 1 1]);
            axis(app.MaxProjection, 'off');
%             set(app.XY,'WindowKeyPressFcn',@(~,~)disp('figure'),...
%                 'HitTest','off');
%             f = figure();
%             set(f,'WindowStyle','modal')
%             set(f, 'WindowKeyPressFcn',   @(h,e) disp('Hi'));
            
%             zoom(app.XY, 'on');
%             zoom(app.YZ, 'on');
%             zoom(app.MaxProjection, 'on');
            
        end

        % Menu selected function: DecimateMenu
        function DecimateButtonPushed(app, event)
            prompt = {'Enter the decimation factor:','Enter the method (linear/nearest/cubic):'};
            title = 'Decimation Parameters';
            dims = [1 35];
            definput = {'2','linear'};
            answer = inputdlg(prompt,title,dims,definput);
            
            app.dataset = decimate_frame(app.dataset, round(size(app.dataset)/str2double(answer{1})), answer{2});
            
            app.datastack{end+1} = app.dataset;
            
            app.ZSlider.Limits = [1, size(app.dataset, 3)];
            
%             app.XSliderS.Limits = [1, size(app.dataset, 1)];
%             app.YSliderS.Limits = [1, size(app.dataset, 2)];
            app.ZSliderS.Limits = [1, size(app.dataset, 3)];
            if ~isempty(app.supervoxels)
                app.supervoxels.mean = app.supervoxels.mean/str2double(answer{1});
                app.supervoxels.cov = app.supervoxels.cov/str2double(answer{1});
            end
            
            app.update_views();
            
        end

        % Menu selected function: ThresholdMenu
        function AdThreshButtonPushed(app, event)
            prompt = {'Enter the method (adaptive/fixed/otsu):', 'Enter the parameter for the model:'};
            title = 'Thresholding Parameters';
            dims = [1 35];
            definput = {'adaptive', '0.9'};
            answer = inputdlg(prompt,title,dims,definput);
            
            app.datastack{end+1} = app.dataset;
            app.dataset = threshold_frame(app.dataset, answer{1}, str2double(answer{2}));
            app.update_views();
        end

        % Menu selected function: HistogramMatchingMenu
        function HistMatchButtonPushed(app, event)
            app.datastack{end+1} = app.dataset;
            app.dataset = histmatch_frame(double(app.dataset));
            app.update_views();
        end

        % Menu selected function: RemoveSmallObjectsMenu
        function RmSmallObjButtonPushed(app, event)
            prompt = {'Enter the minimum size of components:'};
            title = 'Remove Small Objects Parameters';
            dims = [1 35];
            definput = {'50'};
            answer = inputdlg(prompt,title,dims,definput);
            
            app.datastack{end+1} = app.dataset;
            app.dataset = rm_smallobj_frame(app.dataset, str2num(answer{1}));
            app.update_views();
        end

        % Callback function
        function MaxProjSliderValueChanged(app, event)
            app.datastack{end+1} = app.dataset;
            value = round(app.MaxProjSlider.Value);
            tmp = app.dataset;
            app.dataset = zeros(size(tmp, 1), size(tmp, 2), (size(tmp, 3) - 2*(value - 1)), size(tmp, 4));
            size(app.dataset)
            for z = 1: size(app.dataset, 3)
                app.dataset(:,:,z,:) = max(tmp(:,:,z:value+z-1,:), [], 3);                
            end
            app.update_views();
        end

        % Callback function
        function SegmentsListBoxValueChanged(app, event)
            value = app.SegmentsListBox.Value;
        end

        % Callback function
        function UndoButtonPushed(app, event)
            app.dataset = app.datastack{end};
            app.datastack(end) = [];
            
            app.update_views();
        end

        % Callback function
        function MaxProjSSliderValueChanged(app, event)
            value = app.MaxProjSSlider.Value;
            
        end

        % Callback function
        function SaveLabelButtonPushed(app, event)
            app.saved_labels{end + 1} = app.LabelEditField.Value;
            app.saved_datasets{end + 1} = app.dataset;
            
            app.SavedLabelDropDown.Items = app.saved_labels;
            app.SavedLabelDropDown_2.Items = app.saved_labels;
        end

        % Callback function
        function TimeValueChanging(app, event)
            changingValue = event.Value;
            app.update_view(changingValue);
            app.update_views();
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)
            z = round(event.Value); cla(app.XY);
            xy = squeeze(app.vdataset(:,:,z,:,:)); 
            image(xy, 'Parent', app.XY); hold(app.XY, 'on');

            if ~isempty(app.supervoxels) && ~isempty(app.supervoxels(1))
                c = app.supervoxels(1).mean;
                xygt = c(c(:,3)>z-0.5 & c(:,3)<z+0.5, [2,1]);
                a = scatter(app.XY, xygt(:, 1), xygt(:, 2), 80, 'w*');
                a.ButtonDownFcn = {@app.PointSelected};
            end
%             if ~isempty(app.vrecon)
%                 app.ZSliderSValueChanging(event);
%             end
        end

        % Callback function
        function XSliderValueChanging(app, event)
            x = round(event.Value); cla(app.YZ);
            frame = squeeze(app.vdataset(:,:,:,:,1));
            view = double(frame)/double(prctile(frame(:), 99.9));
            zy = squeeze(view(x, :, :, :)); 
            image(zy, 'Parent', app.YZ); hold(app.YZ, 'on');
            if ~isempty(app.supervoxels) && ~isempty(app.supervoxels(1))
                c = app.supervoxels(1).mean;
                zygt = c(c(:, 1) > x - 0.5 & c(:, 1) < x + 0.5, [2, 3]);
                scatter(app.YZ, zygt(:, 1), zygt(:, 2), 'b+'); 
            end
            
            if ~isempty(app.vrecon)
                app.XSliderSValueChanging(event);
            end
        end

        % Callback function
        function YSliderValueChanging(app, event)
            y = round(event.Value); cla(app.MaxProjection);      
            frame = squeeze(app.vdataset(:,:,:,:,1));
            view = double(frame)/double(prctile(app.vdataset(:), 99.9));
            xz = permute(squeeze(view(:, y, :, :)), [2, 1, 3]);
            image(xz, 'Parent', app.MaxProjection); hold(app.MaxProjection, 'on');
            if ~isempty(app.supervoxels) && ~isempty(app.supervoxels(1))
                c = app.supervoxels(1).mean;      
                zxgt = c(c(:, 2) > y - 0.5 & c(:, 2) < y + 0.5, [1, 3]);
                scatter(app.MaxProjection, zxgt(:, 1), zxgt(:, 2), 'bx');
            end
            if ~isempty(app.vrecon)
                app.YSliderSValueChanging(event);
            end
        end

        % Value changing function: ZSliderS
        function ZSliderSValueChanging(app, event)

            z = round(event.Value); cla(app.XYS);
            c = app.supervoxels(1).mean;            
            frame = squeeze(app.vrecon(:,:,:,:,1));
            view = double(frame)/double(prctile(app.vdataset(:), 99.9));
            xy = squeeze(view(:,:,z,:)); 
            image(xy, 'Parent', app.XYS); hold(app.XYS, 'on');
            xygt = c(c(:, 3) > z - 0.5 & c(:, 3) < z + 0.5, [2, 1]);
            scatter(app.XYS, xygt(:, 1), xygt(:, 2), 'b+');
            
        end

        % Value changing function: XSliderS
        function XSliderSValueChanging(app, event)
            x = round(event.Value); cla(app.YZS);
            c = app.supervoxels(1).mean;            
            frame = squeeze(app.vrecon(:,:,:,:,1));
            view = double(frame)/double(prctile(app.vdataset(:), 99.9));
            zy = squeeze(view(x, :, :, :)); 
            image(zy, 'Parent', app.YZS); hold(app.YZS, 'on');
            zygt = c(c(:, 1) > x - 0.5 & c(:, 1) < x + 0.5, [2, 3]);
            scatter(app.YZS, zygt(:, 1), zygt(:, 2), 'b+'); 
            
        end

        % Value changing function: YSliderS
        function YSliderSValueChanging(app, event)
            y = round(event.Value); cla(app.XZS);
            c = app.supervoxels(1).mean;            
            frame = squeeze(app.vrecon(:,:,:,:,1));
            view = double(frame)/double(prctile(app.vdataset(:), 99.9));
            xz = permute(squeeze(view(:, y, :, :)), [2, 1, 3]);
            image(xz, 'Parent', app.XZS); hold(app.XZS, 'on');
            zxgt = c(c(:, 2) > y - 0.5 & c(:, 2) < y + 0.5, [1, 3]);
            scatter(app.XZS, zxgt(:, 1), zxgt(:, 2), 'bx');
        end

        % Callback function
        function SavedLabelDropDownValueChanged(app, event)
            value = app.SavedLabelDropDown.Value;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            
            app.update_views();
        end

        % Button pushed function: RunMPButton
        function RunMPButtonPushed(app, event)
            prompt = {'Enter the window size:','Enter the trunction value:', 'Enter the number of neurons:'};
            title = 'MP Parameters';
            dims = [1 35];
            definput = {app.params.mp.default_window,app.params.mp.default_trunc,app.params.mp.default_k};
            answer = inputdlg(prompt,title,dims,definput);
            hnsz = str2num(answer{1});
            trunc = str2num(answer{2});
            k = str2num(answer{3});
            app.k = k;
            [path, name, ~] = fileparts(app.DatasetEditField.Value);
            try
                load([path, '/sp_mpgfs_bca_', name, '_', num2str(k), '_c', '.mat']);
                k=size(sp_mpgfs_bca.mean,1);
                try
                    load([path, '/autoID_', name, '_', num2str(k), '.mat']);
                    app.ids = autoID.id;
                catch
                end
            catch
                try
                    load([path, '/sp_mpgfs_bca_', name, '_', num2str(k), '.mat']);
                catch
                    filter = get_filter(hnsz, [6,6,6], 0);
                    sp = matching_pursuit_gaussian_two_step(app.dataset, filter, k, trunc);
                    sp_mpgfs_bca = matching_pursuit_bca(app.dataset, sp, hnsz, trunc, 2);
                    save([path, '/sp_mpgfs_bca_', name, '_', num2str(k), '.mat'], 'sp_mpgfs_bca', 'hnsz', 'trunc');
                end
            end
            app.supervoxels = sp_mpgfs_bca;
            
%             sz = size(app.dataset);
%             [app.recon, app.shapes] = sp2video(app.supervoxels, [sz(1:3),size(app.supervoxels.color,2),1], hnsz, trunc);
            
            app.update_views();

        end

        % Button pushed function: AnnotateButton
        function AnnotateButtonPushed(app, event)
            app.auto_id.annotations{app.selectedidx} = app.IDEditField.Value;
            app.auto_id.LL(app.selectedidx,:)= -100000;
            
            [assignments, assignment_det,  assignment_prob_ranks, assignment_prob_probs, rank_uncertain] = compute_assignments(app.auto_id.LL);
            [ids, ids_prob, cols, rows] = find_ids(app.auto_id.compartment, assignment_prob_ranks, assignments, 'mog');
            
            app.auto_id.assignments = assignments;
            app.auto_id.assignment_det = assignment_det;
            app.auto_id.assignment_prob_ranks = assignment_prob_ranks;
            app.auto_id.assignment_prob_probs = assignment_prob_probs;
            app.auto_id.rank_uncertain = rank_uncertain;
            app.auto_id.ids = ids;
            app.auto_id.ids_prob = ids_prob;
            
            
            strs = arrayfun(@(x) strjoin(ids_prob(x,:), ','), 1:size(ids_prob,1), 'UniformOutput', 0)';
            app.NeuronsListBox.Items = strcat(ids(rank_uncertain),'----', strs(rank_uncertain));
            
            app.NeuronsListBoxValueChanged(app.NeuronsListBox);
            
        end

        % Value changed function: Rotation
        function RotationValueChanged(app, event)
            value = app.Rotation.Value;
            R = makehgtform('xrotate', value);
            dataset = [];
            sz=size(app.dataset);
            Rin = imref3d(sz(1:3));
            Rin.XWorldLimits = Rin.XWorldLimits-2*mean(Rin.XWorldLimits);
            Rin.YWorldLimits = Rin.YWorldLimits-2*mean(Rin.YWorldLimits);
            Rin.ZWorldLimits = Rin.ZWorldLimits-2*mean(Rin.ZWorldLimits);

            for c=1:size(app.dataset,4)
                dataset(:,:,:,c) = imwarp(app.dataset(:,:,:,c,:),Rin, affine3d(R),'cubic');
            end
            
            if ~isempty(app.supervoxels)
                app.supervoxels.mean(:,[2,1,3]) = (app.supervoxels.mean(:,[2,1,3])-(sz([2,1,3])+1))*R(1:3,1:3)+(sz([2,1,3])+1);
                for i=1:size(app.supervoxels.mean,1)
                    app.supervoxels.cov(i,[2,1,3],[2,1,3])=R(1:3,1:3)*squeeze(app.supervoxels.cov(i,[2,1,3],[2,1,3]))*R(1:3,1:3)';
                end
%                 hnsz = [15,15,11]; trunc = 80;
%                 [app.recon, app.shapes] = sp2video(app.supervoxels, [size(dataset),1], hnsz, trunc);
            end
            app.dataset = dataset;
            
            
            app.XSlider.Limits = [1, size(app.dataset, 1)];
            app.YSlider.Limits = [1, size(app.dataset, 2)];
            app.ZSlider.Limits = [1, size(app.dataset, 3)];
            
            app.XSliderS.Limits = [1, size(app.dataset, 1)];
            app.YSliderS.Limits = [1, size(app.dataset, 2)];
            app.ZSliderS.Limits = [1, size(app.dataset, 3)];
            
            app.Rotation.Value = 0;
            app.update_views();
             
        end

        % Button pushed function: AutoIDButton
        function AutoIDButtonPushed(app, event)
            prompt = {'Head or Tail:'};
            title = 'AutoID Parameters';
            dims = [1 35];
            definput = {'tail'};
            answer = inputdlg(prompt,title,dims,definput);
           
            LL = auto_id(app.supervoxels, answer{1}, 'mog');
            [assignments, assignment_det,  assignment_prob_ranks, assignment_prob_probs, rank_uncertain] = compute_assignments(LL);
            [ids, ids_prob, cols, rows] = find_ids(answer{1}, assignment_prob_ranks, assignments, 'mog');
            
            app.auto_id.LL = LL;
            app.auto_id.assignments = assignments;
            app.auto_id.assignment_det = assignment_det;
            app.auto_id.assignment_prob_ranks = assignment_prob_ranks;
            app.auto_id.assignment_prob_probs = assignment_prob_probs;
            app.auto_id.rank_uncertain = rank_uncertain;
            app.auto_id.ids = ids;
            app.auto_id.ids_prob = ids_prob;
            app.auto_id.rows = rows;
            app.auto_id.rowcolss = cols;
            app.auto_id.compartment = answer{1};
            
            strs = arrayfun(@(x) strjoin(ids_prob(x,:), ','), 1:size(ids_prob,1), 'UniformOutput', 0)';
            app.NeuronsListBox.Items = strcat(ids(rank_uncertain),'----', strs(rank_uncertain));
        end

        % Button pushed function: SaveMPButton
        function SaveMPButtonPushed(app, event)
            [path, name, ~] = fileparts(app.DatasetEditField.Value);
            load([path, '/sp_mpgfs_bca_', name, '_', num2str(app.k), '.mat']);
            sp_mpgfs_bca = app.supervoxels;
            save([path, '/sp_mpgfs_bca_', name, '_', num2str(app.k), '_c.mat'], 'sp_mpgfs_bca', 'hnsz', 'trunc');
        end

        % Button pushed function: SaveIDsButton
        function SaveIDsButtonPushed(app, event)
            [path, name, ~] = fileparts(app.DatasetEditField.Value);
            autoID = app.supervoxels;
            autoID.id = app.auto_id.annotations;
            save([path, '/autoID_', name, '_', num2str(size(autoID.mean,1)), '.mat'], 'autoID');
        end

        % Menu selected function: ZScoringMenu
        function ZScoringMenuSelected(app, event)
            app.datastack{end+1} = app.dataset;
            app.dataset = zscore_frame(double(app.dataset));
            app.update_views();
        end

        % Menu selected function: RotateX180Menu
        function RotateX180MenuSelected(app, event)
            app.dataset = app.dataset(:,end:-1:1,end:-1:1,:,:);
            if ~isempty(app.supervoxels)
                app.supervoxels.mean = [app.supervoxels.mean(:,1), ...
                                        size(app.dataset,2)-app.supervoxels.mean(:,2), ...
                                        size(app.dataset,3)-app.supervoxels.mean(:,3)];
                % Multiply cov by rotation matrix
            end
            app.update_views();
        end

        % Menu selected function: RotateY180Menu
        function RotateY180MenuSelected(app, event)
            app.dataset = app.dataset(end:-1:1,:,end:-1:1,:,:);
            if ~isempty(app.supervoxels)
                app.supervoxels.mean = [size(app.dataset,1)-app.supervoxels.mean(:,1), ...
                                        app.supervoxels.mean(:,2), ...
                                        size(app.dataset,3)-app.supervoxels.mean(:,3)];
                % Multiply cov by rotation matrix
            end
            app.update_views();
        end

        % Value changed function: AutoIDDropDown
        function AutoIDDropDownValueChanged(app, event)
            
            value = app.AutoIDDropDown.Value;
            values = strsplit(value,',');
            
            app.IDEditField.Value = values{2};
            app.UserUncertainty.Value = str2num(values{1});
            
        end

        % Value changed function: NeuronsListBox
        function NeuronsListBoxValueChanged(app, event)
            value = app.NeuronsListBox.Value;
            index = app.auto_id.rank_uncertain(strcmp(app.NeuronsListBox.Items, value));
            pos = app.supervoxels.mean(index,:);
            app.ZSlider.Value=pos(3);
            app.ZSliderValueChanging(app.ZSlider);
            hit = [];
            hit.Button = 1;
            hit.IntersectionPoint = pos([2,1,3]);
            app.PointSelected(app.XY.Children(1), hit);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.RemoveSmallObjectsMenu = uimenu(app.Menu);
            app.RemoveSmallObjectsMenu.MenuSelectedFcn = createCallbackFcn(app, @RmSmallObjButtonPushed, true);
            app.RemoveSmallObjectsMenu.Text = 'Remove Small Objects';
            app.ZScoringMenu = uimenu(app.Menu);
            app.ZScoringMenu.MenuSelectedFcn = createCallbackFcn(app, @ZScoringMenuSelected, true);
            app.ZScoringMenu.Text = 'Z Scoring';
            app.HistogramMatchingMenu = uimenu(app.Menu);
            app.HistogramMatchingMenu.MenuSelectedFcn = createCallbackFcn(app, @HistMatchButtonPushed, true);
            app.HistogramMatchingMenu.Text = 'Histogram Matching';
            app.ThresholdMenu = uimenu(app.Menu);
            app.ThresholdMenu.MenuSelectedFcn = createCallbackFcn(app, @AdThreshButtonPushed, true);
            app.ThresholdMenu.Text = 'Threshold';
            app.DecimateMenu = uimenu(app.Menu);
            app.DecimateMenu.MenuSelectedFcn = createCallbackFcn(app, @DecimateButtonPushed, true);
            app.DecimateMenu.Text = 'Decimate';
            app.RotationMenu = uimenu(app.Menu);
            app.RotationMenu.Text = 'Rotation';
            app.RotateX180Menu = uimenu(app.RotationMenu);
            app.RotateX180Menu.MenuSelectedFcn = createCallbackFcn(app, @RotateX180MenuSelected, true);
            app.RotateX180Menu.Text = 'Rotate X 180';
            app.RotateY180Menu = uimenu(app.RotationMenu);
            app.RotateY180Menu.MenuSelectedFcn = createCallbackFcn(app, @RotateY180MenuSelected, true);
            app.RotateY180Menu.Text = 'Rotate Y 180';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PreprocessTab = uitab(app.TabGroup);
            app.PreprocessTab.Title = 'Preprocess';
            app.LoadButton = uibutton(app.PreprocessTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.XY = uiaxes(app.PreprocessTab);
            app.MaxProjection = uiaxes(app.PreprocessTab);
            app.DatasetEditFieldLabel = uilabel(app.PreprocessTab);
            app.DatasetEditFieldLabel.Text = 'Dataset';
            app.DatasetEditField = uieditfield(app.PreprocessTab, 'text');
            app.ZSliderLabel = uilabel(app.PreprocessTab);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.PreprocessTab);
            app.ZSlider.Limits = [1 100];
            app.ZSlider.MajorTicks = [];
            app.ZSlider.MajorTickLabels = {};
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.ZSlider.MinorTicks = [];
            app.ZSlider.Value = 1;
            app.RDropDownLabel = uilabel(app.PreprocessTab);
            app.RDropDownLabel.Text = 'R';
            app.RDropDown = uidropdown(app.PreprocessTab);
            app.RDropDown.Items = {'1', '2', '3'};
            app.RDropDown.Value = '1';
            app.GDropDownLabel = uilabel(app.PreprocessTab);
            app.GDropDownLabel.Text = 'G';
            app.GDropDown = uidropdown(app.PreprocessTab);
            app.GDropDown.Items = {'1', '2', '3'};
            app.GDropDown.Value = '2';
            app.BDropDownLabel = uilabel(app.PreprocessTab);
            app.BDropDownLabel.Text = 'B';
            app.BDropDown = uidropdown(app.PreprocessTab);
            app.BDropDown.Items = {'1', '2', '3'};
            app.BDropDown.Value = '3';
            app.UpdateButton = uibutton(app.PreprocessTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.AutoIDDropDownLabel = uilabel(app.PreprocessTab);
            app.AutoIDDropDownLabel.Text = 'AutoID';
            app.AutoIDDropDown = uidropdown(app.PreprocessTab);
            app.AutoIDDropDown.Items = {};
            app.AutoIDDropDown.ValueChangedFcn = createCallbackFcn(app, @AutoIDDropDownValueChanged, true);
            app.AutoIDDropDown.Value = {};
            app.IDEditField = uieditfield(app.PreprocessTab, 'text');
            app.AnnotateButton = uibutton(app.PreprocessTab, 'push');
            app.AnnotateButton.ButtonPushedFcn = createCallbackFcn(app, @AnnotateButtonPushed, true);
            app.AnnotateButton.Text = 'Annotate';
            app.Rotation = uislider(app.PreprocessTab);
            app.Rotation.Limits = [-0.05 0.05];
            app.Rotation.MajorTicks = [];
            app.Rotation.MajorTickLabels = {};
            app.Rotation.ValueChangedFcn = createCallbackFcn(app, @RotationValueChanged, true);
            app.Rotation.MinorTicks = [];
            app.NeuronsListBoxLabel = uilabel(app.PreprocessTab);
            app.NeuronsListBoxLabel.Text = 'Neurons';
            app.NeuronsListBox = uilistbox(app.PreprocessTab);
            app.NeuronsListBox.Items = {};
            app.NeuronsListBox.ValueChangedFcn = createCallbackFcn(app, @NeuronsListBoxValueChanged, true);
            app.NeuronsListBox.Value = {};
            app.RunMPButton = uibutton(app.PreprocessTab, 'push');
            app.RunMPButton.ButtonPushedFcn = createCallbackFcn(app, @RunMPButtonPushed, true);
            app.RunMPButton.Text = 'Run MP';
            app.SaveMPButton = uibutton(app.PreprocessTab, 'push');
            app.SaveMPButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMPButtonPushed, true);
            app.SaveMPButton.Text = 'Save MP';
            app.AutoIDButton = uibutton(app.PreprocessTab, 'push');
            app.AutoIDButton.ButtonPushedFcn = createCallbackFcn(app, @AutoIDButtonPushed, true);
            app.AutoIDButton.Text = 'AutoID';
            app.SaveIDsButton = uibutton(app.PreprocessTab, 'push');
            app.SaveIDsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveIDsButtonPushed, true);
            app.SaveIDsButton.Text = 'Save IDs';
            app.UserUncertainty = uieditfield(app.PreprocessTab, 'numeric');
            app.MPTab = uitab(app.TabGroup);
            app.MPTab.Title = 'MP';
            app.XYS = uiaxes(app.MPTab);
            app.YZS = uiaxes(app.MPTab);
            app.XZS = uiaxes(app.MPTab);
            app.XSlider_2Label = uilabel(app.MPTab);
            app.XSlider_2Label.Text = 'X';
            app.XSliderS = uislider(app.MPTab);
            app.XSliderS.Limits = [1 100];
            app.XSliderS.MajorTicks = [];
            app.XSliderS.MajorTickLabels = {};
            app.XSliderS.ValueChangingFcn = createCallbackFcn(app, @XSliderSValueChanging, true);
            app.XSliderS.MinorTicks = [];
            app.XSliderS.Value = 1;
            app.YSlider_2Label = uilabel(app.MPTab);
            app.YSlider_2Label.Text = 'Y';
            app.YSliderS = uislider(app.MPTab);
            app.YSliderS.Limits = [1 100];
            app.YSliderS.MajorTicks = [];
            app.YSliderS.MajorTickLabels = {};
            app.YSliderS.ValueChangingFcn = createCallbackFcn(app, @YSliderSValueChanging, true);
            app.YSliderS.MinorTicks = [];
            app.YSliderS.Value = 1;
            app.ZSlider_2Label = uilabel(app.MPTab);
            app.ZSlider_2Label.Text = 'Z';
            app.ZSliderS = uislider(app.MPTab);
            app.ZSliderS.Limits = [1 100];
            app.ZSliderS.MajorTicks = [];
            app.ZSliderS.MajorTickLabels = {};
            app.ZSliderS.ValueChangingFcn = createCallbackFcn(app, @ZSliderSValueChanging, true);
            app.ZSliderS.MinorTicks = [];
            app.ZSliderS.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = visualize_light

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end